classdef DSP_Project2_AndreiD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ControlPanel                 matlab.ui.container.Panel
        PlayFilteredButton_2         matlab.ui.control.Button
        ApplyFilterButton            matlab.ui.control.Button
        PlayOriginalButton           matlab.ui.control.Button
        EditField_2                  matlab.ui.control.EditField
        EditField                    matlab.ui.control.EditField
        SaveButton                   matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        DSPProject1AudioFilterLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        filename;   % Name of input audio file
        input;      % Input audio file
        output;     % Input audio file with the desired effects applied
        Fs;         % Sampling frequency of the input audio file
        Ts;         % Sampling period of the input audio file
        t;          % Time in seconds of the input/output audio file
        t_final;    % Max value of the input audio file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Store input file and setup file properties
            [app.filename,PathName,FilterIndex] = uigetfile('.wav');
            [app.input,app.Fs] = audioread(app.filename);
            app.Ts = 1/app.Fs;
            app.t_final = length(app.input)/app.Fs;
            app.t = (0:app.Ts:app.t_final-app.Ts)';

            % Note: 1xN = Mono, 2xN = Stereo
            % If input is mono, make it stereo
            if isvector(app.input)
                app.input = [app.input app.input];
            end
           
            % Play and plot input signal
            sound(app.input,app.Fs);
            % Set the altered output sound file to input
            app.output = app.input;

            % Dont input on file
            app.EditField.Value = app.filename;
            
            % Disable file input fields until sound stops playing
            set(app.LoadButton, 'Enable', 'off');
            set(app.EditField, 'Enable', 'off');
            if strcmpi(app.LoadButton.Enable, 'off')
                pause(app.t_final)
                set(app.LoadButton, 'Enable', 'on');
                set(app.EditField, 'Enable', 'on');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DSPProject1AudioFilterLabel = uilabel(app.UIFigure);
            app.DSPProject1AudioFilterLabel.Text = 'DSP Project 1 - Audio Filter ';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.LoadButton = uibutton(app.ControlPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.ControlPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.EditField = uieditfield(app.ControlPanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Placeholder = 'Input file name';
            app.EditField_2 = uieditfield(app.ControlPanel, 'text');
            app.EditField_2.Placeholder = 'Name your filtered file';
            app.PlayOriginalButton = uibutton(app.ControlPanel, 'push');
            app.PlayOriginalButton.Text = 'Play Original';
            app.ApplyFilterButton = uibutton(app.ControlPanel, 'push');
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.PlayFilteredButton_2 = uibutton(app.ControlPanel, 'push');
            app.PlayFilteredButton_2.Text = 'Play Filtered';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP_Project2_AndreiD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end